classdef MATLAB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LM35basedArduinoThermometerUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        ClearFigureButton               matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        ArduinoSerialPortDropDown       matlab.ui.control.DropDown
        ArduinoSerialPortDropDownLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        arduinoObj  % Arduino serial object
        connected   % true if connection with Arduino is already active
        historyLength % history lenght of the strip chart
        data
    end

    methods (Access = private)

        function readAndPlotData(app, src, ~)

            % Read the ASCII data from the serialport object.
            str = readline(src);
            tokens = split(str, ',');
            tokens = str2double(tokens)';
            if ~(sum(isnan(tokens)) > 0)
                app.data = [app.data; tokens];
            end
            
            if (size(app.data, 1) >  app.historyLength)
                app.data = app.data(end - app.historyLength : end, :);
            end
            plot(app.UIAxes, app.data);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.LM35basedArduinoThermometerUIFigure, 'center');
            app.connected = false;
            app.historyLength = 100;
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.XLim = [0, app.historyLength];
            app.UpdateButtonPushed(app);

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if (isempty(serialportlist("available")))
                app.ArduinoSerialPortDropDown.Items = "";
            else
                app.ArduinoSerialPortDropDown.Items = serialportlist("available");
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (app.connected == false)
                app.connected = true;
                app.ConnectButton.Text = 'Disconnect';
                app.UpdateButton.Enable = false;
                app.ArduinoSerialPortDropDown.Enable = false;
                app.arduinoObj = serialport(app.ArduinoSerialPortDropDown.Value, 115200);
                configureTerminator(app.arduinoObj,"LF");
                flush(app.arduinoObj);
                app.arduinoObj.UserData = struct("Data",[],"Count",1);
                configureCallback(app.arduinoObj,"terminator",@app.readAndPlotData);
            else
                app.ConnectButton.Text = 'Connect';
                app.UpdateButton.Enable = true;
                app.ArduinoSerialPortDropDown.Enable = true;
                app.connected = false;
                delete(app.arduinoObj);
            end
        end

        % Button pushed function: ClearFigureButton
        function ClearFigureButtonPushed(app, event)
            delete(plot(app.UIAxes, app.data));
            app.data = [];
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LM35basedArduinoThermometerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {354, 354};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LM35basedArduinoThermometerUIFigure = uifigure('Visible', 'off');
            app.LM35basedArduinoThermometerUIFigure.Name = 'LM35-based Arduino Thermometer';
            app.LM35basedArduinoThermometerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LM35basedArduinoThermometerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ArduinoSerialPortDropDownLabel = uilabel(app.LeftPanel);
            app.ArduinoSerialPortDropDownLabel.Text = 'Arduino Serial Port:';
            app.ArduinoSerialPortDropDown = uidropdown(app.LeftPanel);
            app.ArduinoSerialPortDropDown.Items = {};
            app.ArduinoSerialPortDropDown.Value = {};
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ClearFigureButton = uibutton(app.LeftPanel, 'push');
            app.ClearFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFigureButtonPushed, true);
            app.ClearFigureButton.Text = 'Clear Figure';
            app.SaveDataButton = uibutton(app.LeftPanel, 'push');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'temperature')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.LM35basedArduinoThermometerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LM35basedArduinoThermometerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LM35basedArduinoThermometerUIFigure)
        end
    end
end